classdef Air_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GenerateButton                  matlab.ui.control.Button
        WaveformGeneratorLabel          matlab.ui.control.Label
        FFTLengthEditFieldLabel         matlab.ui.control.Label
        FFTLengthEditField              matlab.ui.control.NumericEditField
        InsertDCNullCheckBox            matlab.ui.control.CheckBox
        CyclicPrefixLengthEditFieldLabel  matlab.ui.control.Label
        CyclicPrefixLengthEditField     matlab.ui.control.NumericEditField
        WindowingCheckBox               matlab.ui.control.CheckBox
        PioltInputCheckBox              matlab.ui.control.CheckBox
        OFDMSymbolsEditFieldLabel       matlab.ui.control.Label
        OFDMSymbolsEditField            matlab.ui.control.NumericEditField
        TransmitAntennasEditFieldLabel  matlab.ui.control.Label
        TransmitAntennasEditField       matlab.ui.control.NumericEditField
        SubcarrierSpacingHzEditFieldLabel  matlab.ui.control.Label
        SubcarrierSpacingHzEditField    matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
    end

    
    properties (Access = private)
        Pax 
    end
    
    methods (Access = public)
        
        function test(app)
           hMod = comm.OFDMModulator;
           hMod.FFTLength = 128;
           hMod.NumSymbols = 2;
           
           disp(hMod)
           
           showResourceMapping(hMod)
        end
        
        function test2(app)
            %sine = dsp.SineWave(amp,freq,phase,Name,Value)
            sine1 = dsp.SineWave();
            sine1.Amplitude = app.EditAmplitude.Value;
            sine1.Frequency = app.EditFrequency.Value;
            sine1.PhaseOffset = app.EditPhaseOffset.Value * pi/2;
            sine1.SamplesPerFrame = app.EditSamplesPerFrame.Value;
            y = sine1();
            
            plot(app.UIAxes , y );
            
        end
        
        function test3(app)

            hMod = comm.OFDMModulator;

            hMod.FFTLength = 128;
            hMod.NumSymbols = 2;

            hMod.PilotInputPort = true;

            hMod.PilotCarrierIndices = [12; 56; 89; 100];

            hMod.NumTransmitAntennas = 1 ;

            disp(hMod);
            showResourceMapping(hMod);

            %gx = uiaxes("Parent", app.Panel);

        %    plot(app.Panel , gx);
        %    p = uipanel;
        %    p = uipanel(app.Panel);

        %    app.Panel(geoaxes);

        end
        
        function test4(app)
            
        %% Generate OFDM Waveform
        % OFDM configuration:
        ofdmMod = comm.OFDMModulator('FFTLength', app.FFTLengthEditField.Value, ...
        'NumGuardBandCarriers', [6;5],...
        'InsertDCNull', app.InsertDCNullCheckBox.Value,...
        'CyclicPrefixLength', app.CyclicPrefixLengthEditField.Value, ...
        'Windowing', app.WindowingCheckBox.Value, ...
        'NumSymbols', app.OFDMSymbolsEditField.Value, ...
        'NumTransmitAntennas', app.TransmitAntennasEditField.Value, ...
        'PilotInputPort', app.PioltInputCheckBox.Value);
        
        M = 4; 	 % Modulation order
        % input bit source:
        in = randi([0 1], 10600, 1);
        
        dataInput = qammod(in, M, 'gray', 'InputType', 'bit', 'UnitAveragePower', true);
        ofdmInfo = info(ofdmMod);
        ofdmSize = ofdmInfo.DataInputSize;
        dataInput = reshape(dataInput, ofdmSize);
        
        % waveform generation:
        waveform = ofdmMod(dataInput); % y axis, x axis = time
        
        %covert time signal to frequency signal then plot to axis
        
        
        Fs = 6.4 * app.SubcarrierSpacingHzEditField.Value; % sample rate of waveform
        
        %% Visualize OFDM Waveform
        % Spectrum Analyzer
        spectrum = dsp.SpectrumAnalyzer('SampleRate', Fs);
        spectrum(waveform);
        release(spectrum);
        
        % OFDM Subcarrier Mapping
        showResourceMapping(ofdmMod);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.test4()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.WaveformGeneratorLabel = uilabel(app.UIFigure);
            app.WaveformGeneratorLabel.Text = 'Waveform Generator';
            app.FFTLengthEditFieldLabel = uilabel(app.UIFigure);
            app.FFTLengthEditFieldLabel.Text = 'FFT Length';
            app.FFTLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.FFTLengthEditField.Value = 64;
            app.InsertDCNullCheckBox = uicheckbox(app.UIFigure);
            app.InsertDCNullCheckBox.Text = 'Insert DC Null';
            app.CyclicPrefixLengthEditFieldLabel = uilabel(app.UIFigure);
            app.CyclicPrefixLengthEditFieldLabel.Text = 'Cyclic Prefix Length';
            app.CyclicPrefixLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.CyclicPrefixLengthEditField.Value = 16;
            app.WindowingCheckBox = uicheckbox(app.UIFigure);
            app.WindowingCheckBox.Text = 'Windowing';
            app.PioltInputCheckBox = uicheckbox(app.UIFigure);
            app.PioltInputCheckBox.Text = 'Piolt Input';
            app.OFDMSymbolsEditFieldLabel = uilabel(app.UIFigure);
            app.OFDMSymbolsEditFieldLabel.Text = 'OFDM Symbols';
            app.OFDMSymbolsEditField = uieditfield(app.UIFigure, 'numeric');
            app.OFDMSymbolsEditField.Value = 100;
            app.TransmitAntennasEditFieldLabel = uilabel(app.UIFigure);
            app.TransmitAntennasEditFieldLabel.Text = 'Transmit Antennas';
            app.TransmitAntennasEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransmitAntennasEditField.Value = 1;
            app.SubcarrierSpacingHzEditFieldLabel = uilabel(app.UIFigure);
            app.SubcarrierSpacingHzEditFieldLabel.Text = 'Subcarrier Spacing (Hz)';
            app.SubcarrierSpacingHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SubcarrierSpacingHzEditField.Value = 100000;
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Air_Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end